classdef Digital_Communication_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        Tab6                         matlab.ui.container.Tab
        Panel37                      matlab.ui.container.Panel
        Button16                     matlab.ui.control.Button
        TextArea3_3                  matlab.ui.control.TextArea
        TextArea3_4                  matlab.ui.control.TextArea
        Button18_2                   matlab.ui.control.Button
        Button18_3                   matlab.ui.control.Button
        Button18                     matlab.ui.control.Button
        TextArea3_5                  matlab.ui.control.TextArea
        Today_Date_2                 matlab.ui.control.TextArea
        DesignandTransmissionButton  matlab.ui.control.Button
        PerformanceAnalysisButton    matlab.ui.control.Button
        AboutButton                  matlab.ui.control.Button
        Tab5                         matlab.ui.container.Tab
        Panel23                      matlab.ui.container.Panel
        Label83                      matlab.ui.control.Label
        Quantizer_level              matlab.ui.control.DropDown
        Label86                      matlab.ui.control.Label
        Modulation_type              matlab.ui.control.DropDown
        Label87                      matlab.ui.control.Label
        Modulation_order             matlab.ui.control.DropDown
        Label89                      matlab.ui.control.Label
        SNR                          matlab.ui.control.NumericEditField
        Panel24                      matlab.ui.container.Panel
        Label90                      matlab.ui.control.Label
        Fading_Channel               matlab.ui.control.DropDown
        Label91                      matlab.ui.control.Label
        Channel                      matlab.ui.control.DropDown
        PhaseShiftDegreeLabel        matlab.ui.control.Label
        Phase_Shift                  matlab.ui.control.NumericEditField
        Panel25                      matlab.ui.container.Panel
        Label92                      matlab.ui.control.Label
        Symbol_Timing                matlab.ui.control.DropDown
        Label93                      matlab.ui.control.Label
        Equalizer                    matlab.ui.control.DropDown
        Label94                      matlab.ui.control.Label
        Carrier_Recovery             matlab.ui.control.DropDown
        File_Path                    matlab.ui.control.EditField
        Browser                      matlab.ui.control.Button
        Run                          matlab.ui.control.StateButton
        Panel31                      matlab.ui.container.Panel
        Figure_1                     matlab.ui.control.UIAxes
        Figure_1_Plots               matlab.ui.container.ButtonGroup
        Audio_Plot_Rx                matlab.ui.control.ToggleButton
        Image_Plot_Rx                matlab.ui.control.ToggleButton
        Image_Plot_Tx                matlab.ui.control.ToggleButton
        Audio_Plot_Tx                matlab.ui.control.ToggleButton
        Button19                     matlab.ui.control.Button
        Button20                     matlab.ui.control.Button
        Button21                     matlab.ui.control.Button
        Button22                     matlab.ui.control.Button
        Figure_2                     matlab.ui.control.UIAxes
        Figure_2_Plots               matlab.ui.container.ButtonGroup
        Carrier_Estimator_Plot       matlab.ui.control.ToggleButton
        Symbol_Timing_Plot           matlab.ui.control.ToggleButton
        Equalization_Plot            matlab.ui.control.ToggleButton
        Constellation_Tx_Plot        matlab.ui.control.ToggleButton
        Constellation_Rx_Plot        matlab.ui.control.ToggleButton
        ButtonGroup11                matlab.ui.container.ButtonGroup
        Label107                     matlab.ui.control.Label
        BER                          matlab.ui.control.NumericEditField
        Panel26                      matlab.ui.container.Panel
        Description_3                matlab.ui.control.TextArea
        Selection                    matlab.ui.control.DropDown
        TextArea3_6                  matlab.ui.control.TextArea
        Today_Date                   matlab.ui.control.TextArea
        Figure_3                     matlab.ui.container.Panel
        Image_Figure                 matlab.ui.control.UIAxes
        Tab5_2                       matlab.ui.container.Tab
        Panel23_2                    matlab.ui.container.Panel
        BlockSelectionLabel          matlab.ui.control.Label
        Selected_Block               matlab.ui.control.DropDown
        GuidingEquationsandVariablesPanel  matlab.ui.container.Panel
        Description                  matlab.ui.control.TextArea
        Run_2                        matlab.ui.control.StateButton
        Panel                        matlab.ui.container.Panel
        Previous                     matlab.ui.control.Button
        Next                         matlab.ui.control.Button
        Noisy_Channel                matlab.ui.control.CheckBox
        NoisyChannelLabel            matlab.ui.control.Label
        SymbolTimingDesignLabel      matlab.ui.control.Label
        SNR_Value                    matlab.ui.control.NumericEditField
        SNREditFieldLabel            matlab.ui.control.Label
        StepSizeKLabel               matlab.ui.control.Label
        K_constant                   matlab.ui.control.NumericEditField
        TimingPhaseDeviationLabel    matlab.ui.control.Label
        SampleSpacing_delta          matlab.ui.control.NumericEditField
        Panel31_2                    matlab.ui.container.Panel
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes_1                     matlab.ui.control.UIAxes
        Title_Bar_1                  matlab.ui.control.Label
        Title_Bar_2                  matlab.ui.control.Label
        TextArea3_7                  matlab.ui.control.TextArea
        Today_Date_3                 matlab.ui.control.TextArea
        Panel31_3                    matlab.ui.container.Panel
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_4                     matlab.ui.control.UIAxes
        Title_Bar_3                  matlab.ui.control.Label
        Title_Bar_4                  matlab.ui.control.Label
        Panel23_3                    matlab.ui.container.Panel
        Blocks                       matlab.ui.control.Button
        Panel_2                      matlab.ui.container.Panel
        Prev_Image                   matlab.ui.control.Button
        Next_Image                   matlab.ui.control.Button
        AboutTab                     matlab.ui.container.Tab
        Panel26_2                    matlab.ui.container.Panel
        Description_5                matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        Property % Description
        audio_data % Original audio signal
        quantize_level % quantization level applied to audio signal
        image_data % Original image signal
        image_size % Image size
        
        modulated_signal % modulated signal at the transmitter
        faded_noisy_signal % received noisy signal at the receiver
        rx_data % received pulse shaped signal at the receiver
        equalized_signal % received equalized signal at the receiver
        demodulated_signal % demodulated signal at the receiver
        integer_format_rx % received image at the receiver
        decoded_data % received audio signal at the receiver
        phase_shift % phase offset at the transmitter
        phase_estimated % estimated phase at the receiver
        baseband_data % received baseband signal at the receiver
        sample_points % sampling times in symbol timing recovery
        sample_values % sampled values at the sampling points in symbol timing recovery
        Next_Page=0
        Next_Block=0
        next_fig=0


        shift_elg % timing phase deviation for early late gate algorithm
        shift_mm % timing phase deviation for mueller and muller algorithm
        shift_gb % timing phase deviation for gradient based algorithm
        
        sample_points_elg % sample points from the early late gate algorithm
        sample_points_mm % sample points from the mueller and muller algorithm
        sample_points_gb % sample points from the gradient based algorithm
        
        sample_values_elg % sample values from the early late gate algorithm
        sample_values_mm % sample values from the mueller and muller algorithm
        sample_values_gb % sample values from the gradient based algorithm
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Today_Date.Value = datestr(datetime('today'));
            app.Today_Date_2.Value = datestr(datetime('today'));
            app.Today_Date_3.Value = datestr(datetime('today'));
        end

        % Button pushed function: Browser
        function BrowserButtonPushed(app, event)
            if (strcmp(app.Selection.Value,'Image'))
                file_path = file_browser('image');
                app.File_Path.Value = file_path;
                [app.image_data,app.image_size] = input_image(file_path);
            elseif (strcmp(app.Selection.Value,'Audio'))
                file_path = file_browser('audio');
                app.File_Path.Value = file_path;
                app.audio_data = input_audio(file_path,10000);
            end
            
        end

        % Button pushed function: Button21
        function Button21Pushed(app, event)
            app.next_fig=app.next_fig+1;
            if(app.Figure_2_Plots.SelectedObject==app.Symbol_Timing_Plot)
                plot(app.Figure_2,1:length(app.baseband_data),app.baseband_data,'-r',app.sample_points,app.sample_values,'-c*');
                xlim(app.Figure_2,[app.next_fig*10000,10000+app.next_fig*10000]);
                title( app.Figure_2,'Symbol Timing Recovery');
            end
        end

        % Button pushed function: Button22
        function Button22Pushed(app, event)
            if(app.next_fig>0)
                app.next_fig=app.next_fig-1;
                if(app.Figure_2_Plots.SelectedObject==app.Symbol_Timing_Plot)
                    plot(app.Figure_2,1:length(app.baseband_data),app.baseband_data,'-r',app.sample_points,app.sample_values,'-c*');
                    xlim(app.Figure_2,[app.next_fig*10000,10000+app.next_fig*10000]);
                    title( app.Figure_2,'Symbol Timing Recovery');
                end
            end
        end

        % Value changed function: Channel
        function ChannelValueChanged(app, event)
            value = app.Channel.Value;
            if strcmp(app.Channel.Value,'AWGN')
                app.Fading_Channel.Value='No Fading';
                app.Equalizer.Value='Without Equalizer';
                app.Fading_Channel.Enable='off';
                app.Equalizer.Enable='off';
                app.Phase_Shift.Enable='on';
            else
                app.Fading_Channel.Value='Measured Channel';
                app.Equalizer.Value='Constant Modulus Algorithm';
                app.Fading_Channel.Enable='on';
                app.Equalizer.Enable='on';
                app.Phase_Shift.Value=0;
                app.Carrier_Recovery.Value='Without Recovery';
                app.Carrier_Recovery.Enable='off';
            end
        end

        % Value changed function: Description, Selected_Block
        function DescriptionValueChanged(app, event)
            if (strcmp(app.Selected_Block.Value,'Early-Late Gate'))
                app.Description.Value='e = K * { ‖y(n+δ)‖^2 - ‖y(n-δ)‖^2  }, where K = constant = 2.5, δ = sample spacing = 0.25*T, y(n) = nth sample and T = symbol period = 100 samples';
                
            elseif (strcmp(app.Selected_Block.Value,'Mueller and Muller'))
                app.Description.Value='e = K * { ŷ(n)*y(n) - ŷ(n+1)*y(n-δ) }, where K = constant, T = symbol period = 100 samples, δ = sample shift = T, y(n) = nth sample, ŷ(n) = slicer output.';
                
            elseif (strcmp(app.Selected_Block.Value,'Gradient Based'))
                app.Description.Value='e = K * ( y(n) * y(n-1) ), where K = constant = 10, T = symbol period = 100 samples, δ = sample shift = T/2, y(n) = nth sample.';
            elseif (strcmp(app.Selected_Block.Value,'Carrier Recovery'))
                %app.SNR_Value.Enable='off';
                app.Description.Value='K = Loop filter constant and fc = Loop filter cutoff frequency (fraction of sampling frequency).';
            elseif (strcmp(app.Selected_Block.Value,'Equalizer'))
                app.K_constant.Value=1;
                app.Description.Value='Insert in step size(k), 1 for Measured channel, 2 for JTC channel,3 for Exponential channel, 4 for Exponential with Phase Shift ';
            end
            value=app.Description.Value;
        end

        % Selection changed function: Figure_1_Plots
        function Figure_1_PlotsSelectionChanged(app, event)
            selectedButton = app.Figure_1_Plots.SelectedObject;
            
            if(selectedButton==app.Audio_Plot_Tx)
                len=2000;
                play_audio(app.audio_data)   ;
                plot( app.Figure_1,1:len,app.audio_data(1:len) );
                title( app.Figure_1,'Received Audio vs Samples')
            elseif (selectedButton==app.Image_Plot_Tx)
                image_display( app.image_data,app.image_size,app.Image_Figure );
                title( app.Image_Figure,'Transmitted Image');
            elseif(selectedButton==app.Audio_Plot_Rx)
                len=2000;
                play_audio(app.decoded_data)   ;
                plot( app.Figure_1,1:len,app.decoded_data(1:len) );
                title( app.Figure_1,'Received Audio vs Samples');
            elseif(selectedButton==app.Image_Plot_Rx)
                image_display(app.integer_format_rx,app.image_size,app.Image_Figure);
                title( app.Image_Figure,'Received Image');
            end
            
        end

        % Selection changed function: Figure_2_Plots
        function Figure_2_PlotsSelectionChanged(app, event)
            selectedButton = app.Figure_2_Plots.SelectedObject;
            cla(app.Figure_2);
            hold(app.Figure_2, 'on');
            
            if(selectedButton==app.Constellation_Tx_Plot)
                scatter( app.Figure_2,real(app.modulated_signal),imag(app.modulated_signal),'filled' );
                xlim(app.Figure_2,[-1*str2double(app.Modulation_order.Value)/2,str2double(app.Modulation_order.Value)/2]);
                ylim(app.Figure_2,[-1*str2double(app.Modulation_order.Value)/2,str2double(app.Modulation_order.Value)/2]);
                title( app.Figure_2,'Transmitted Signal Constellation');
            elseif(selectedButton==app.Constellation_Rx_Plot)
                scatter( app.Figure_2,real(app.faded_noisy_signal),imag(app.faded_noisy_signal),'filled' );
                xlim( app.Figure_2,[-1*str2double(app.Modulation_order.Value)/2,str2double(app.Modulation_order.Value)/2] );
                ylim( app.Figure_2,[-1*str2double(app.Modulation_order.Value)/2,str2double(app.Modulation_order.Value)/2] );
                title( app.Figure_2,'Received Noisy Signal Constellation');
            elseif(selectedButton==app.Carrier_Estimator_Plot)
                len=10000;
                plot_samples=1:len;
                phase_offset=app.phase_shift*ones(1,len)*180/pi;
                phase_estimate=app.phase_estimated(1:len)*180/pi;
                plot( app.Figure_2,plot_samples,phase_offset,'-r',plot_samples,phase_estimate );
                title( app.Figure_2,'Costas Loop: Phase Angle Vs Samples' );
            elseif(selectedButton==app.Equalization_Plot)
                len=length(app.equalized_signal);
                scatter( app.Figure_2,real(app.equalized_signal(4.5*len/5:len)),imag(app.equalized_signal(4.5*len/5:len)),'filled' );
                xlim(app.Figure_2,[-1*str2double(app.Modulation_order.Value)/2,str2double(app.Modulation_order.Value)/2]);
                ylim(app.Figure_2,[-1*str2double(app.Modulation_order.Value)/2,str2double(app.Modulation_order.Value)/2]);
                title( app.Figure_2,'Received Equalized Signal Constellation');
            elseif(selectedButton==app.Symbol_Timing_Plot)
                plot(app.Figure_2,1:length(app.baseband_data),app.baseband_data,'-r',app.sample_points,app.sample_values,'-c*');
                xlim(app.Figure_2,[1,10000]);
                title( app.Figure_2,'Symbol Timing Recovery');
            end
            hold(app.Figure_2, 'off');
            
        end

        % Button pushed function: Image_Browser
        function Image_BrowserButtonPushed(app, event)
            
        end

        % Button pushed function: Next
        function NextButtonPushed(app, event)
            app.Next_Page=mod((app.Next_Page+1),4);
            if (strcmp(app.Selected_Block.Value,'Symbol Timing Recovery'))
                if (app.Next_Page==1||app.Next_Page==3)
                    stem(app.UIAxes_1,abs(app.shift_elg(1:3000)),'-r');
                    app.Title_Bar_1.Text=strcat ('Early Late Gate: Shift Vs Samples k=300');
                    
                    stem(app.UIAxes_2,abs(app.shift_gb(1:3000)),'-r');
                    app.Title_Bar_2.Text=strcat ('Gradient Based: Shift Vs Samples k=150');
                    
                    stem(app.UIAxes_3,abs(app.shift_mm(1:3000)),'-r');
                    app.Title_Bar_3.Text=strcat ('Mueller and Muller: Shift Vs Samples k=100');
                elseif (app.Next_Page==0||app.Next_Page==2)
                    plot(app.UIAxes_1,1:length(app.rx_data),app.rx_data,'-r',app.sample_points_elg,app.sample_values_elg,'-c*');
                    app.Title_Bar_1.Text=strcat ('Early Late Gate: Baseband Signal Vs Samples K=300');
                    
                    plot(app.UIAxes_2,1:length(app.rx_data),app.rx_data,'-r',app.sample_points_gb,app.sample_values_gb,'-c*');
                    app.Title_Bar_2.Text=strcat ('Gradient Based: Baseband Signal Vs Samples K=150');
                    
                    
                    plot(app.UIAxes_3,1:length(app.rx_data),app.rx_data,'-r',app.sample_points_mm,app.sample_values_mm,'-c*');
                    app.Title_Bar_3.Text=strcat ('Mueller and Muller: Baseband Signal Vs Samples K=100');
                    
                    
                else
                    Run_2ValueChanged(app, event);
                end
            elseif (strcmp(app.Selected_Block.Value,'Carrier Recovery'))
                Run_2ValueChanged(app, event);
                
            end
            
        end

        % Button pushed function: Next_Image
        function Next_ImageButtonPushed(app, event)
            app.Next_Block=mod(app.Next_Block+1,2);
            if (app.Next_Block==0)
                app.Blocks.Icon='Algorithm_Best.PNG';
            elseif(app.Next_Block==1)
                app.Blocks.Icon='End_to_End_Final.PNG';
            end
        end

        % Button pushed function: Prev_Image
        function Prev_ImageButtonPushed(app, event)
            app.Next_Block=mod(app.Next_Block+1,2);
            if (app.Next_Block==0)
                app.Blocks.Icon='Algorithm_Best.PNG';
            elseif(app.Next_Block==1)
                app.Blocks.Icon='End_to_End_Final.PNG';
            end
        end

        % Button pushed function: Previous
        function PreviousButtonPushed(app, event)
            app.Next_Page=mod((app.Next_Page+3),4);
            if (strcmp(app.Selected_Block.Value,'Symbol Timing Recovery'))
                if (app.Next_Page==1||app.Next_Page==3)
                    stem(app.UIAxes_1,abs(app.shift_elg(1:3000)),'-r');
                    app.Title_Bar_1.Text=strcat ('Early Late Gate: Shift Vs Samples k=300');
                    
                    stem(app.UIAxes_2,abs(app.shift_gb(1:3000)),'-r');
                    app.Title_Bar_2.Text=strcat ('Gradient Based: Shift Vs Samples k=150');
                    
                    stem(app.UIAxes_3,abs(app.shift_mm(1:3000)),'-r');
                    app.Title_Bar_3.Text=strcat ('Mueller and Muller: Shift Vs Samples k=100');
                elseif (app.Next_Page==0||app.Next_Page==2)
                    plot(app.UIAxes_1,1:length(app.rx_data),app.rx_data,'-r',app.sample_points_elg,app.sample_values_elg,'-c*');
                    app.Title_Bar_1.Text=strcat ('Early Late Gate: Baseband Signal Vs Samples K=300');
                    
                    plot(app.UIAxes_2,1:length(app.rx_data),app.rx_data,'-r',app.sample_points_gb,app.sample_values_gb,'-c*');
                    app.Title_Bar_2.Text=strcat ('Gradient Based: Baseband Signal Vs Samples K=150');
                    
                    
                    plot(app.UIAxes_3,1:length(app.rx_data),app.rx_data,'-r',app.sample_points_mm,app.sample_values_mm,'-c*');
                    app.Title_Bar_3.Text=strcat ('Mueller and Muller: Baseband Signal Vs Samples K=100');
                    
                    
                else
                    Run_2ValueChanged(app, event);
                end
            elseif (strcmp(app.Selected_Block.Value,'Carrier Recovery'))
                Run_2ValueChanged(app, event);
            end
        end

        % Value changed function: Run
        function RunValueChanged(app, event)
            value = app.Run.Value;
            
            k_elg=2.5;
            k_mul=30;
            k_grad=3;
            delta=25;
            
            if (strcmp(app.Selection.Value,'Random Data'))
                
                if (strcmp(app.Channel.Value,'AWGN + Fading') && (app.Phase_Shift.Value > 0))
                    data=randi([0,1],1,200000);
                else
                    data=randi([0,1],1,101000);
                end
                
                [integer_format_tx,added] = data_to_int(data,log2(str2double(app.Modulation_order.Value)));
                [app.modulated_signal,modulated_signal_length] = modulator(integer_format_tx,app.Modulation_type.Value,str2double(app.Modulation_order.Value));
                
                if (strcmp(app.Channel.Value,'AWGN'))
                    app.faded_noisy_signal=awgn_channel(app.modulated_signal,app.SNR.Value);
                else
                    [ faded_signal,channel_length ] = fading_channel( app.modulated_signal,app.Fading_Channel.Value );
                    app.faded_noisy_signal=awgn_channel(faded_signal,app.SNR.Value);
                end
                
                app.phase_shift=app.Phase_Shift.Value*pi/180;
                
                if (strcmp(app.Fading_Channel.Value,'No Fading'))
                    baseband_signal_rx = square_root_raised_cosine_filter(app.faded_noisy_signal,'tx');
                    tx_passband=passband_transmitter(baseband_signal_rx,app.phase_shift);
                    app.phase_estimated=costas_carrier_recovery(tx_passband,app.Modulation_type.Value,0.15,0.05);
                    if abs(max(app.phase_estimated))<=abs(min(app.phase_estimated))
                        app.phase_estimated=-1*app.phase_estimated;
                    end
                    
                    baseband_signal_tx = passband_receiver( tx_passband,app.phase_estimated );
                else
                    app.faded_noisy_signal=app.faded_noisy_signal*(cos(app.phase_shift)+1i*sin(app.phase_shift));
                    baseband_signal_rx = square_root_raised_cosine_filter(app.faded_noisy_signal,'tx');
                    baseband_signal_tx = baseband_signal_rx;
                    
                end
                
                app.baseband_data=square_root_raised_cosine_filter(baseband_signal_tx,'rx');
                
                try
                    
                    if strcmp(app.Symbol_Timing.Value,'Early Late Gate')
                        [app.sample_values,app.sample_points] = early_late_gate(app.baseband_data,k_elg,delta);
                    elseif strcmp(app.Symbol_Timing.Value,'Gradient Based')
                        [ app.sample_values,app.sample_points,shift ]=gradient_based( app.baseband_data,k_grad );
                    elseif strcmp(app.Symbol_Timing.Value,'Mueller and Muller')
                        [ app.sample_values,app.sample_points,shift ]=mueller_and_muller( app.baseband_data,k_mul );
                    end
                    
                catch exception
                    
                end
                
                if (strcmp(app.Channel.Value,'AWGN + Fading'))
                    
                    if strcmp(app.Equalizer.Value,'Constant Modulus Algorithm')
                        [constant_module,constellation_mean] = GetQAMConstantModule(str2double(app.Modulation_order.Value));
                        [app.equalized_signal,tap_weight] = CMA_equalizer(app.sample_values,modulated_signal_length,constellation_mean,constant_module,channel_length);
                    elseif strcmp(app.Equalizer.Value,'Multiple Modulus Algorithm')
                        [constant_module_real,constant_module_imag,constellation_mean] = GetQAMConstantModuleForMMA(str2double(app.Modulation_order.Value));
                        [app.equalized_signal,tap_weight] = MMA_equalizer(app.sample_values,modulated_signal_length,constellation_mean,constant_module_real,constant_module_imag,channel_length);
                    elseif strcmp(app.Equalizer.Value,'VSCMA_equalizer')
                        [app.equalized_signal] = VSCMA_equalizer(app.sample_values,length(app.sample_values));    
                    elseif strcmp(app.Equalizer.Value,'VSCMADDLMS_equalizer')
                        [app.equalized_signal] = VSCMA_equalizer(app.sample_values,length(app.sample_values));    
                    elseif strcmp(app.Equalizer.Value,'DFECMA_equalizer')
                        [app.equalized_signal] = VSCMA_equalizer(app.sample_values,length(app.sample_values));    
                    end 
                else
                    app.equalized_signal=app.sample_values;
                end
               
                app.demodulated_signal = demodulator(app.equalized_signal,app.Modulation_type.Value,str2double(app.Modulation_order.Value));
                
                data_format_rx_randomized = int_to_data(app.demodulated_signal,log2(str2double(app.Modulation_order.Value)),added);
                
                len=length(data_format_rx_randomized);
                [~,ratio]=biterr(data_format_rx_randomized(len-99999:len),data(len-99999:len));
                app.BER.Value=ratio;
                
            elseif (strcmp(app.Selection.Value,'Image'))
                
                data_format_tx = int_to_data(app.image_data,8,0);
                [data_format_tx_randomized, data_randomizer]=randomizer(data_format_tx);
                [integer_format_tx,added] = data_to_int(data_format_tx_randomized,log2(str2double(app.Modulation_order.Value)));
                [app.modulated_signal,modulated_signal_length] = modulator(integer_format_tx,app.Modulation_type.Value,str2double(app.Modulation_order.Value));
                
                if (strcmp(app.Channel.Value,'AWGN'))
                    app.faded_noisy_signal=awgn_channel(app.modulated_signal,app.SNR.Value);
                else
                    [ faded_signal,channel_length ] = fading_channel( app.modulated_signal,app.Fading_Channel.Value  );
                    app.faded_noisy_signal=awgn_channel(faded_signal,app.SNR.Value);
                end
                
                app.phase_shift=app.Phase_Shift.Value*pi/180;
                
                if (strcmp(app.Fading_Channel.Value,'No Fading'))
                    baseband_signal_rx = square_root_raised_cosine_filter(app.faded_noisy_signal,'tx');
                    tx_passband=passband_transmitter(baseband_signal_rx,app.phase_shift);
                    app.phase_estimated=costas_carrier_recovery(tx_passband,app.Modulation_type.Value,0.15,0.05);
                    if abs(max(app.phase_estimated))<=abs(min(app.phase_estimated))
                        app.phase_estimated=-1*app.phase_estimated;
                    end
                    
                    baseband_signal_tx = passband_receiver( tx_passband,app.phase_estimated );
                else
                    app.faded_noisy_signal=app.faded_noisy_signal*(cos(app.phase_shift)+1i*sin(app.phase_shift));
                    baseband_signal_rx = square_root_raised_cosine_filter(app.faded_noisy_signal,'tx');
                    baseband_signal_tx = baseband_signal_rx;
                    
                end
                
                app.baseband_data = square_root_raised_cosine_filter(baseband_signal_tx,'rx');
                
                try
                    
                    if strcmp(app.Symbol_Timing.Value,'Early Late Gate')
                        [app.sample_values,app.sample_points] = early_late_gate(app.baseband_data,k_elg,delta);
                    elseif strcmp(app.Symbol_Timing.Value,'Gradient Based')
                        [ app.sample_values,app.sample_points,shift ]=gradient_based( app.baseband_data,k_grad );
                    elseif strcmp(app.Symbol_Timing.Value,'Mueller and Muller')
                        [ app.sample_values,app.sample_points,shift ]=mueller_and_muller( app.baseband_data,k_mul );
                    end
                catch exception
                    
                end
                
                if (strcmp(app.Channel.Value,'AWGN + Fading'))
                    
                    if strcmp(app.Equalizer.Value,'Constant Modulus Algorithm')
                        [constant_module,constellation_mean] = GetQAMConstantModule(str2double(app.Modulation_order.Value));
                        [app.equalized_signal,tap_weight] = CMA_equalizer(app.sample_values,modulated_signal_length,constellation_mean,constant_module,channel_length);
                    elseif strcmp(app.Equalizer.Value,'Multiple Modulus Algorithm')
                        [constant_module_real,constant_module_imag,constellation_mean] = GetQAMConstantModuleForMMA(str2double(app.Modulation_order.Value));
                        [app.equalized_signal,tap_weight] = MMA_equalizer(app.sample_values,modulated_signal_length,constellation_mean,constant_module_real,constant_module_imag,channel_length);
                    elseif strcmp(app.Equalizer.Value,'VSCMA_equalizer')
                        [app.equalized_signal] = VSCMA_equalizer(app.sample_values,length(app.sample_values));
                    elseif strcmp(app.Equalizer.Value,'VSCMADDLMS_equalizer')
                        [app.equalized_signal] = VSCMA_equalizer(app.sample_values,length(app.sample_values));    
                    elseif strcmp(app.Equalizer.Value,'DFECMA_equalizer')
                        [app.equalized_signal] = VSCMA_equalizer(app.sample_values,length(app.sample_values));    
                    end
                else
                    app.equalized_signal= app.sample_values;
                end
                
                app.demodulated_signal = demodulator(app.equalized_signal,app.Modulation_type.Value,str2double(app.Modulation_order.Value));
                data_format_rx_randomized = int_to_data(app.demodulated_signal,log2(str2double(app.Modulation_order.Value)),added);
                data_format_rx=derandomizer(data_format_rx_randomized,data_randomizer);
                app.integer_format_rx = data_to_int(data_format_rx,8);
                
                len=length(data_format_tx_randomized);
                
                if strcmp(app.Equalizer.Value,'VSCMA_equalizer')||strcmp(app.Equalizer.Value,'VSCMADDLMS_equalizer')||strcmp(app.Equalizer.Value,'DFECMA_equalizer')
                else
                [tot,ratio]=biterr(data_format_rx_randomized(len-49999:len),data_format_tx_randomized(len-49999:len));
                app.BER.Value=ratio;
                end
                
            elseif (strcmp(app.Selection.Value,'Audio'))
                
                
                [partition,codebook] = quantize_audio(app.audio_data,str2double(app.Quantizer_level.Value),'normal');
                encoded_data = encoder(app.audio_data,partition,codebook);
                data_format_encoded_data = int_to_data(encoded_data,str2double(app.Quantizer_level.Value),0);
                
                [data_format_tx_randomized, data_randomizer] = randomizer(data_format_encoded_data);
                [integer_format_tx,added] = data_to_int(data_format_tx_randomized,log2(str2double(app.Modulation_order.Value)));
                [app.modulated_signal,modulated_signal_length] = modulator(integer_format_tx,app.Modulation_type.Value,str2double(app.Modulation_order.Value));
                
                if (strcmp(app.Fading_Channel.Value,'AWGN'))
                    app.faded_noisy_signal=awgn_channel(app.modulated_signal,app.SNR.Value);
                else
                    [ faded_signal,channel_length ] = fading_channel( app.modulated_signal,app.Fading_Channel.Value  );
                    app.faded_noisy_signal=awgn_channel(faded_signal,app.SNR.Value);
                end
                
                app.phase_shift=app.Phase_Shift.Value*pi/180;
                
                if (strcmp(app.Fading_Channel.Value,'No Fading'))
                    baseband_signal_rx = square_root_raised_cosine_filter(app.faded_noisy_signal,'tx');
                    tx_passband=passband_transmitter(baseband_signal_rx,app.phase_shift);
                    app.phase_estimated=costas_carrier_recovery(tx_passband,app.Modulation_type.Value,0.15,0.05);
                    if abs(max(app.phase_estimated))<=abs(min(app.phase_estimated))
                        app.phase_estimated=-1*app.phase_estimated;
                    end
                    
                    baseband_signal_tx = passband_receiver( tx_passband,app.phase_estimated );
                else
                    app.faded_noisy_signal=app.faded_noisy_signal*(cos(app.phase_shift)+1i*sin(app.phase_shift));
                    baseband_signal_rx = square_root_raised_cosine_filter(app.faded_noisy_signal,'tx');
                    baseband_signal_tx = baseband_signal_rx;
                    
                end
                
                app.baseband_data = square_root_raised_cosine_filter(baseband_signal_tx,'rx');
                
                try
                    
                    if strcmp(app.Symbol_Timing.Value,'Early Late Gate')
                        [app.sample_values,app.sample_points] = early_late_gate(app.baseband_data,k_elg,delta);
                    elseif strcmp(app.Symbol_Timing.Value,'Gradient Based')
                        [ app.sample_values,app.sample_points,shift ]=gradient_based( app.baseband_data,k_grad );
                    elseif strcmp(app.Symbol_Timing.Value,'Mueller and Muller')
                        [ app.sample_values,app.sample_points,shift ]=mueller_and_muller( app.baseband_data,k_mul );
                    end
                catch exception
                    
                end
                
                if (strcmp(app.Fading_Channel.Value,'AWGN + Fading'))
                    if strcmp(app.Equalizer.Value,'Constant Modulus Algorithm')
                        [constant_module,constellation_mean] = GetQAMConstantModule(str2double(app.Modulation_order.Value));
                        [app.equalized_signal,tap_weight] = CMA_equalizer(app.sample_values,modulated_signal_length,constellation_mean,constant_module,channel_length);
                    elseif strcmp(app.Equalizer.Value,'Multiple Modulus Algorithm')
                        [constant_module_real,constant_module_imag,constellation_mean] = GetQAMConstantModuleForMMA(str2double(app.Modulation_order.Value));
                        [app.equalized_signal,tap_weight] = MMA_equalizer(app.sample_values,modulated_signal_length,constellation_mean,constant_module_real,constant_module_imag,channel_length);
                    elseif strcmp(app.Equalizer.Value,'VSCMA_equalizer')
                        [app.equalized_signal] = VSCMA_equalizer(app.sample_values,length(app.sample_values));
                    elseif strcmp(app.Equalizer.Value,'VSCMADDLMS_equalizer')
                        [app.equalized_signal] = VSCMA_equalizer(app.sample_values,length(app.sample_values));    
                    elseif strcmp(app.Equalizer.Value,'DFECMA_equalizer')
                        [app.equalized_signal] = VSCMA_equalizer(app.sample_values,length(app.sample_values));    
                    end
                else
                    app.equalized_signal= app.sample_values;
                end
                
                app.demodulated_signal = demodulator(app.equalized_signal,app.Modulation_type.Value,str2double(app.Modulation_order.Value));
                data_format_rx_randomized = int_to_data(app.demodulated_signal,log2(str2double(app.Modulation_order.Value)),added);
                data_format_rx=derandomizer(data_format_rx_randomized,data_randomizer);
                
                integer_format_decoded_data = data_to_int(data_format_rx,str2double(app.Quantizer_level.Value));
                app.decoded_data = decoder(integer_format_decoded_data,partition,codebook);
                
                len=length(data_format_rx_randomized);
                
                if strcmp(app.Equalizer.Value,'VSCMA_equalizer')||strcmp(app.Equalizer.Value,'VSCMADDLMS_equalizer')||strcmp(app.Equalizer.Value,'DFECMA_equalizer')
                else
                [tot,ratio]=biterr(data_format_rx_randomized(len-49999:len),data_format_tx_randomized(len-49999:len));
                app.BER.Value=ratio;
                end    
                
            end
            
            app.Run.Value=0;
        end

        % Value changed function: Run_2
        function Run_2ValueChanged(app, event)
            value = app.Run_2.Value;
            if (strcmp(app.Selected_Block.Value,'Symbol Timing Recovery'))
                
                data=randi([0,1],1,100000);
                [integer_format_tx,added] = data_to_int(data,2);
                [app.modulated_signal,modulated_signal_length] = modulator(integer_format_tx,'qam',4);
            
                tx_data=square_root_raised_cosine_filter(app.modulated_signal,'tx');
                if app.Noisy_Channel.Value==0
                    noisy_data=tx_data;
                else
                    noisy_data=awgn(tx_data,app.SNR_Value.Value);
                end
                
                app.rx_data=square_root_raised_cosine_filter(noisy_data,'rx');
                
                try
                    [ app.sample_values_elg,app.sample_points_elg,app.shift_elg ]=early_late_gate( app.rx_data,2.5,25 );
                    
                    app.Title_Bar_1.Text=strcat ('Early Late Gate: Limit Exceeded');
                    app.demodulated_signal = demodulator(app.sample_values_elg,'qam',4);
                    data_format_rx_randomized = int_to_data(app.demodulated_signal,2,added);                    
                    [~,ratio]=biterr(data,data_format_rx_randomized);
     
                    plot(app.UIAxes_1,1:length(app.rx_data),app.rx_data,'-r',app.sample_points_elg,app.sample_values_elg,'-c*');
                    app.Title_Bar_1.Text=strcat ('Early Late Gate: Baseband Signal Vs Samples K=2.5 BER:',num2str(ratio));
                    
                    [ app.sample_values_mm,app.sample_points_mm,app.shift_mm ]=mueller_and_muller( app.rx_data,30);
                    app.demodulated_signal = demodulator(app.sample_values_mm,'qam',4);
                    data_format_rx_randomized = int_to_data(app.demodulated_signal,2,added);                    
                    [~,ratio]=biterr(data,data_format_rx_randomized);
            
                    plot(app.UIAxes_3,1:length(app.rx_data),app.rx_data,'-r',app.sample_points_mm,app.sample_values_mm,'-c*');
                    app.Title_Bar_3.Text=strcat ('Mueller and Muller: Baseband Signal Vs Samples K=30 BER:',num2str(ratio));
                    
                    [ app.sample_values_gb,app.sample_points_gb,app.shift_gb ]=gradient_based( app.rx_data,10 );
                    app.demodulated_signal = demodulator(app.sample_values_gb,'qam',4);
                    data_format_rx_randomized = int_to_data(app.demodulated_signal,2,added);                    
                    [~,ratio]=biterr(data,data_format_rx_randomized);
                    
                    plot(app.UIAxes_2,1:length(app.rx_data),app.rx_data,'-r',app.sample_points_gb,app.sample_values_gb,'-c*');
                    app.Title_Bar_2.Text=strcat ('Gradient Based: Baseband Signal Vs Samples K=10 BER:',num2str(ratio));
                catch exception
                end
                
            elseif (strcmp(app.Selected_Block.Value,'Early-Late Gate'))
                
                data=randi([0,1],1,100000);
                [integer_format_tx,added] = data_to_int(data,2);
                [app.modulated_signal,modulated_signal_length] = modulator(integer_format_tx,'qam',4);
                
                tx_data=square_root_raised_cosine_filter(app.modulated_signal,'tx');
                if app.Noisy_Channel.Value==0
                    noisy_data=tx_data;
                else
                    noisy_data=awgn(tx_data,app.SNR_Value.Value);
                end
                
                app.rx_data=square_root_raised_cosine_filter(noisy_data,'rx');
                
                try
                    [ app.sample_values_elg,app.sample_points_elg,app.shift_elg ]=early_late_gate( app.rx_data,app.K_constant.Value,app.SampleSpacing_delta.Value*100 );
                    
                    app.demodulated_signal = demodulator(app.sample_values_elg,'qam',4);
                    data_format_rx_randomized = int_to_data(app.demodulated_signal,2,added);                    
                    [~,ratio]=biterr(data,data_format_rx_randomized);
                    
                    plot(app.UIAxes_1,1:length(app.rx_data),app.rx_data,'-r',app.sample_points_elg,app.sample_values_elg,'-c*');
                    app.Title_Bar_1.Text=strcat ('Early Late Gate: Baseband Signal Vs Samples BER:',num2str(ratio));
                    
                    plot(app.UIAxes_2,1:5000,app.rx_data(1:5000),'-r',app.sample_points_elg(1:50),app.sample_values_elg(1:50),'-c*');
                    app.Title_Bar_2.Text=strcat ('Early Late Gate: Baseband Signal Vs Samples BER:',num2str(ratio));
                    
                    %stem(app.UIAxes_3,abs(app.shift_elg(1:1000)),'-r');
                    plot(app.UIAxes_3,0:1000-1,abs(app.shift_elg(1:1000)),'-r');
                    app.Title_Bar_3.Text=strcat ('Early Late Gate: Shift Vs Samples');
                    
                catch exception
                    app.Title_Bar_1.Text=strcat ('Early Late Gate: Limit Exceeded');
                end
                
            elseif (strcmp(app.Selected_Block.Value,'Gradient Based'))
                
                data=randi([0,1],1,100000);
                [integer_format_tx,added] = data_to_int(data,2);
                [app.modulated_signal,modulated_signal_length] = modulator(integer_format_tx,'qam',4);
                tx_data=square_root_raised_cosine_filter(app.modulated_signal,'tx');
                if app.Noisy_Channel.Value==0
                    noisy_data=tx_data;
                else
                    noisy_data=awgn(tx_data,app.SNR_Value.Value);
                end
                
                app.rx_data=square_root_raised_cosine_filter(noisy_data,'rx');
                
                try
                    
                    [ app.sample_values_gb,app.sample_points_gb,app.shift_gb ]=gradient_based( app.rx_data,app.K_constant.Value );
                    app.demodulated_signal = demodulator(app.sample_values_gb,'qam',4);
                    data_format_rx_randomized = int_to_data(app.demodulated_signal,2,added);                    
                    [~,ratio]=biterr(data,data_format_rx_randomized);
                    
                    plot(app.UIAxes_1,1:length(app.rx_data),app.rx_data,'-r',app.sample_points_gb,app.sample_values_gb,'-c*');
                    app.Title_Bar_1.Text=strcat ('Gradient Based: Baseband Signal Vs Samples BER:',num2str(ratio));
                    
                    plot(app.UIAxes_2,1:5000,app.rx_data(1:5000),'-r',app.sample_points_gb(1:50),app.sample_values_gb(1:50),'-c*');
                    app.Title_Bar_2.Text=strcat ('Gradient Based: Baseband Signal Vs Samples BER:',num2str(ratio));
                    
                    %stem(app.UIAxes_3,abs(app.shift_gb(1:1000)),'-r');
                    plot(app.UIAxes_3,0:1000-1,abs(app.shift_gb(1:1000)),'-r');
                    app.Title_Bar_3.Text=strcat ('Gradient Based: Shift Vs Samples');
                catch exception
                    app.Title_Bar_1.Text=strcat ('Gradient Based: Limit Exceeded');
                end
                
            elseif (strcmp(app.Selected_Block.Value,'Mueller and Muller'))
                
                data=randi([0,1],1,100000);
                [integer_format_tx,added] = data_to_int(data,2);
                [app.modulated_signal,modulated_signal_length] = modulator(integer_format_tx,'qam',4);
            
                tx_data=square_root_raised_cosine_filter(app.modulated_signal,'tx');
                if app.Noisy_Channel.Value==0
                    noisy_data=tx_data;
                else
                    noisy_data=awgn(tx_data,app.SNR_Value.Value);
                end
                
                app.rx_data=square_root_raised_cosine_filter(noisy_data,'rx');
                
                try
                    [ app.sample_values_mm,app.sample_points_mm,app.shift_mm ]=mueller_and_muller( app.rx_data,app.K_constant.Value);
                    app.demodulated_signal = demodulator(app.sample_values_mm,'qam',4);
                    data_format_rx_randomized = int_to_data(app.demodulated_signal,2,added);                    
                    [~,ratio]=biterr(data,data_format_rx_randomized);
            
                    plot(app.UIAxes_1,1:length(app.rx_data),app.rx_data,'-r',app.sample_points_mm,app.sample_values_mm,'-c*');
                    app.Title_Bar_1.Text=strcat ('Mueller and Muller: Baseband Signal Vs Samples BER:',num2str(ratio));
                    
                    plot(app.UIAxes_2,1:5000,app.rx_data(1:5000),'-r',app.sample_points_mm(1:50),app.sample_values_mm(1:50),'-c*');
                    app.Title_Bar_2.Text=strcat ('Mueller and Muller: Baseband Signal Vs Samples BER:',num2str(ratio));
                    
                    %stem(app.UIAxes_3,abs(app.shift_mm(1:1000)),'-r');
                    plot(app.UIAxes_3,0:1000-1,abs(app.shift_mm(1:1000)),'-r');
                    app.Title_Bar_3.Text=strcat ('Mueller and Muller: Shift Vs Samples');
                catch exception
                    app.Title_Bar_1.Text=strcat ('Mueller and Muller: Limit Exceeded');
                end
                
            elseif (strcmp(app.Selected_Block.Value,'Carrier Recovery'))
                
                if ( app.Next_Page==0||app.Next_Page==2)
                    
                    phase_offset=pi/4;
                    plot_samples=1:200;
                    len=length(plot_samples);
                    phase_estimate_1=zeros(1,len);
                    phase_estimate_2=zeros(1,len);
                    phase_estimate_3=zeros(1,len);
                    phase_estimate_4=zeros(1,len);
                    
                    data=ones(1,200);
                    tx_passband=passband_transmitter(data,phase_offset);
                    
                    for i=1:4
                        
                        k=0.05+(i-1)*0.1;
                        phase_estimate=PLL_carrier_recovery(tx_passband,k,0.05);
                        
                        if i==1
                            phase_estimate_1=phase_estimate;
                        elseif i==2
                            phase_estimate_2=phase_estimate;
                        elseif i==3
                            phase_estimate_3=phase_estimate;
                        elseif i==4
                            phase_estimate_4=phase_estimate;
                        end
                        
                    end
                    
                    phase_offset=phase_offset*180/pi;
                    phase_offset=phase_offset*(ones(1,len));
                    
                    phase_estimate_1=phase_estimate_1*180/pi;
                    phase_estimate_2=phase_estimate_2*180/pi;
                    phase_estimate_3=phase_estimate_3*180/pi;
                    phase_estimate_4=phase_estimate_4*180/pi;
                    
                    plot(app.UIAxes_1,plot_samples,phase_offset,'-r',plot_samples,phase_estimate_1(1:len),'-g',plot_samples,phase_estimate_2(1:len),'-b',plot_samples,phase_estimate_3(1:len),'-k',plot_samples,phase_estimate_4(1:len),'-m');
                    legend(app.UIAxes_1,'input signal angle','k=0.05','k=0.15','k=0.25','k=0.35');
                    app.Title_Bar_1.Text='PLL: Phase Angle Vs Samples for variable k';
                    
                    
                    data=2*randi([0,1],1,2000)-1;
                    %design of the Square Root Raised Cosine filter
                    SRRC_filter=rcosdesign(0.5,4,5);
                    
                    %multiplying the data with the square root raised cosine filter
                    filt_data=upfirdn(data,SRRC_filter,10);
                    
                    phase_offset=pi/4;
                    plot_samples=1:12000;
                    len=length(plot_samples);
                    
                    tx_passband=passband_transmitter(filt_data,phase_offset);
                    
                    for j=1:4
                        
                        k=0.05+(j-1)*0.1;
                        phase_estimate=costas_carrier_recovery(tx_passband,'pam',k,0.05);
                        
                        if j==1
                            phase_estimate_1=phase_estimate;
                        elseif j==2
                            phase_estimate_2=phase_estimate;
                        elseif j==3
                            phase_estimate_3=phase_estimate;
                        elseif j==4
                            phase_estimate_4=phase_estimate;
                        end
                        
                    end
                    
                    phase_offset=phase_offset*180/pi;
                    phase_offset=phase_offset*(ones(1,len));
                    
                    phase_estimate_1=phase_estimate_1*180/pi;
                    phase_estimate_2=phase_estimate_2*180/pi;
                    phase_estimate_3=phase_estimate_3*180/pi;
                    phase_estimate_4=phase_estimate_4*180/pi;
                    
                    plot(app.UIAxes_2,plot_samples,phase_offset,'-r',plot_samples,phase_estimate_1(1:len),'-g',plot_samples,phase_estimate_2(1:len),'-b',plot_samples,phase_estimate_3(1:len),'-k',plot_samples,phase_estimate_4(1:len),'-m');
                    legend(app.UIAxes_2,'input signal angle','k=0.05','k=0.15','k=0.25','k=0.35');
                    app.Title_Bar_2.Text='Costas Loop: Phase Angle Vs Samples for variable k';
                    
                    
                    for h=1:4
                        
                        k=0.05+(h-1)*0.1;
                        phase_estimate=squaring_loop_carrier_recovery(tx_passband,k,0.05);
                        
                        if h==1
                            phase_estimate_1=phase_estimate;
                        elseif h==2
                            phase_estimate_2=phase_estimate;
                        elseif h==3
                            phase_estimate_3=phase_estimate;
                        elseif h==4
                            phase_estimate_4=phase_estimate;
                        end
                        
                    end
                    
                    phase_estimate_1=phase_estimate_1*180/pi;
                    phase_estimate_2=phase_estimate_2*180/pi;
                    phase_estimate_3=phase_estimate_3*180/pi;
                    phase_estimate_4=phase_estimate_4*180/pi;
                    
                    plot(app.UIAxes_3,plot_samples,phase_offset,'-r',plot_samples,phase_estimate_1(1:len),'-g',plot_samples,phase_estimate_2(1:len),'-b',plot_samples,phase_estimate_3(1:len),'-k',plot_samples,phase_estimate_4(1:len),'-m');
                    legend(app.UIAxes_3,'input signal angle','k=0.05','k=0.15','k=0.25','k=0.35');
                    app.Title_Bar_3.Text='Squaring Loop: Phase Angle Vs Samples for variable k';
                    
                elseif ( app.Next_Page==1||app.Next_Page==3 )
                    
                    phase_offset=pi/4;
                    plot_samples=1:200;
                    len=length(plot_samples);
                    phase_estimate_1=zeros(1,len);
                    phase_estimate_2=zeros(1,len);
                    phase_estimate_3=zeros(1,len);
                    phase_estimate_4=zeros(1,len);
                    
                    data=ones(1,200);
                    tx_passband=passband_transmitter(data,phase_offset);
                    
                    for i=1:4
                        
                        h=0.05+(i-1)*0.15;
                        phase_estimate=PLL_carrier_recovery(tx_passband,0.15,h);
                        
                        if i==1
                            phase_estimate_1=phase_estimate;
                        elseif i==2
                            phase_estimate_2=phase_estimate;
                        elseif i==3
                            phase_estimate_3=phase_estimate;
                        elseif i==4
                            phase_estimate_4=phase_estimate;
                        end
                        
                    end
                    
                    phase_offset=phase_offset*180/pi;
                    phase_offset=phase_offset*(ones(1,len));
                    
                    phase_estimate_1=phase_estimate_1*180/pi;
                    phase_estimate_2=phase_estimate_2*180/pi;
                    phase_estimate_3=phase_estimate_3*180/pi;
                    phase_estimate_4=phase_estimate_4*180/pi;
                    
                    plot(app.UIAxes_1,plot_samples,phase_offset,'-r',plot_samples,phase_estimate_1(1:len),'-g',plot_samples,phase_estimate_2(1:len),'-b',plot_samples,phase_estimate_3(1:len),'-k',plot_samples,phase_estimate_4(1:len),'-m');
                    legend(app.UIAxes_1,'input signal angle','fc=0.05*pi','fc=0.2*pi','fc=0.35*pi','fc=0.5*pi');
                    app.Title_Bar_1.Text='PLL: Phase Angle Vs Samples for variable fc';
                    
                    
                    data=2*randi([0,1],1,200)-1;
                    %design of the Square Root Raised Cosine filter
                    SRRC_filter=rcosdesign(0.5,4,5);
                    
                    %multiplying the data with the square root raised cosine filter
                    filt_data=upfirdn(data,SRRC_filter,10);
                    
                    phase_offset=pi/4;
                    plot_samples=1:2000;
                    len=length(plot_samples);
                    
                    tx_passband=passband_transmitter(filt_data,phase_offset);
                    
                    for j=1:4
                        
                        h=0.05+(j-1)*0.15;
                        phase_estimate=costas_carrier_recovery(tx_passband,'pam',0.15,h);
                        
                        if j==1
                            phase_estimate_1=phase_estimate;
                        elseif j==2
                            phase_estimate_2=phase_estimate;
                        elseif j==3
                            phase_estimate_3=phase_estimate;
                        elseif j==4
                            phase_estimate_4=phase_estimate;
                        end
                        
                    end
                    
                    phase_offset=phase_offset*180/pi;
                    phase_offset=phase_offset*(ones(1,len));
                    
                    phase_estimate_1=phase_estimate_1*180/pi;
                    phase_estimate_2=phase_estimate_2*180/pi;
                    phase_estimate_3=phase_estimate_3*180/pi;
                    phase_estimate_4=phase_estimate_4*180/pi;
                    
                    
                    plot(app.UIAxes_2,plot_samples,phase_offset,'-r',plot_samples,phase_estimate_1(1:len),'-g',plot_samples,phase_estimate_2(1:len),'-b',plot_samples,phase_estimate_3(1:len),'-k',plot_samples,phase_estimate_4(1:len),'-m');
                    legend(app.UIAxes_2,'input signal angle','fc=0.05*pi','fc=0.20*pi','fc=0.35*pi','fc=0.5*pi');
                    app.Title_Bar_2.Text='Costas Loop: Phase Angle Vs Samples for variable fc';
                    
                    
                    for h=1:4
                        
                        k=0.05+(h-1)*0.15;
                        phase_estimate=squaring_loop_carrier_recovery(tx_passband,0.5,k);
                        
                        if h==1
                            phase_estimate_1=phase_estimate;
                        elseif h==2
                            phase_estimate_2=phase_estimate;
                        elseif h==3
                            phase_estimate_3=phase_estimate;
                        elseif h==4
                            phase_estimate_4=phase_estimate;
                        end
                        
                    end
                    
                    phase_estimate_1=phase_estimate_1*180/pi;
                    phase_estimate_2=phase_estimate_2*180/pi;
                    phase_estimate_3=phase_estimate_3*180/pi;
                    phase_estimate_4=phase_estimate_4*180/pi;
                    
                    plot(app.UIAxes_3,plot_samples,phase_offset,'-r',plot_samples,phase_estimate_1(1:len),'-g',plot_samples,phase_estimate_2(1:len),'-b',plot_samples,phase_estimate_3(1:len),'-k',plot_samples,phase_estimate_4(1:len),'-m');
                    legend(app.UIAxes_3,'input signal angle','fc=0.05*pi','fc=0.20*pi','fc=0.35*pi','fc=0.50*pi');
                    app.Title_Bar_3.Text='Squaring Loop: Phase Angle Vs Samples for variable fc';
                    
                end
                
            elseif (strcmp(app.Selected_Block.Value,'Equalizer'))
                
                data=randi([0,1],1,100000);
                [integer_format_tx,added] = data_to_int(data,2);
                [app.modulated_signal,modulated_signal_length] = modulator(integer_format_tx,'QAM',4);
                data=randi([0,1],1,100000);
                [integer_format_tx,added] = data_to_int(data,2);
                [app.modulated_signal,modulated_signal_length] = modulator(integer_format_tx,'qam',4);
            
                if (app.K_constant.Value==1)
                    [ faded_signal,channel_length ] = fading_channel( app.modulated_signal ,'Measured Channel');
                elseif (app.K_constant.Value==2)
                    [ faded_signal,channel_length ] = fading_channel( app.modulated_signal ,'JTC Channel');
                elseif (app.K_constant.Value==3)
                    [ faded_signal,channel_length ] = fading_channel( app.modulated_signal ,'Exponential Channel');
                elseif (app.K_constant.Value==4)
                    [ faded_signal,channel_length ] = fading_channel( app.modulated_signal ,'With Phase Shift');
                end
                
                app.faded_noisy_signal=awgn_channel(faded_signal,app.SNR_Value.Value);
                
                [constant_module_real,constant_module_imag,constellation_mean] = GetQAMConstantModuleForMMA(4);
                [equalized_signal_m,tap_weight_m] = MMA_equalizer(app.faded_noisy_signal,modulated_signal_length,constellation_mean,constant_module_real,constant_module_imag,channel_length);
                demodulated_signal_m = demodulator(equalized_signal_m,'QAM',4);
                data_format_rx_randomized_m = int_to_data(demodulated_signal_m,2,added);
                
                [~,ratio_m]=biterr(data_format_rx_randomized_m(10000:100000),data(10000:100000));
                
                [constant_module,constellation_mean] = GetQAMConstantModule(4);
                [equalized_signal_c,tap_weight_c] = CMA_equalizer(app.faded_noisy_signal,modulated_signal_length,constellation_mean,constant_module,channel_length);
                demodulated_signal_c = demodulator(equalized_signal_c,'QAM',4);
                data_format_rx_randomized_c = int_to_data(demodulated_signal_c,2,added);
                
                [~,ratio_c]=biterr(data_format_rx_randomized_c(10000:100000),data(10000:100000));
                
                scatter( app.UIAxes_1,real(app.faded_noisy_signal),imag(app.faded_noisy_signal),'filled' );
                xlim(app.UIAxes_1,[-2,2]);
                ylim(app.UIAxes_1,[-2,2]);
                app.Title_Bar_1.Text='Transmitted Signal Constellation';
                
                app.Title_Bar_3.Text=strcat ('MMA Equalizer BER:',num2str(ratio_m));
                scatter( app.UIAxes_3,real(equalized_signal_m(40000:50000)),imag(equalized_signal_m(40000:50000)),'filled' );
                xlim(app.UIAxes_3,[-2,2]);
                ylim(app.UIAxes_3,[-2,2]);
                app.Title_Bar_3.Text=strcat('MMA Equalized Signal Constellation BER:' ,num2str(ratio_m));
                
                app.Title_Bar_2.Text=strcat ('CMA Equalizer BER:',num2str(ratio_c));
                scatter( app.UIAxes_2,real(equalized_signal_c(40000:50000)),imag(equalized_signal_c(40000:50000)),'filled' );
                xlim(app.UIAxes_2,[-2,2]);
                ylim(app.UIAxes_2,[-2,2]);
                app.Title_Bar_2.Text=strcat('CMA Equalized Signal Constellation BER:',num2str(ratio_c));
                
            end
            
            app.Run_2.Value = 0;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Digital Communication System';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab6 = uitab(app.TabGroup);
            app.Tab6.Title = 'Main Page';
            app.Panel37 = uipanel(app.Tab6);
            app.Button16 = uibutton(app.Panel37, 'push');
            app.Button16.Icon = 'images5.jpeg';
            app.Button16.Text = '';
            app.TextArea3_3 = uitextarea(app.Panel37);
            app.TextArea3_3.Editable = 'off';
            app.TextArea3_3.Value = {'Welcome to the World of Digital Communication  '; 'System design.                                               ________________                                                               '};
            app.TextArea3_4 = uitextarea(app.Panel37);
            app.TextArea3_4.Editable = 'off';
            app.TextArea3_4.Value = {'This application simulates the design, performance analysis, and transmission of data through successive blocks of a Digital communication system.'};
            app.Button18_2 = uibutton(app.Panel37, 'push');
            app.Button18_2.Icon = 'images8.jpeg';
            app.Button18_2.Text = '';
            app.Button18_3 = uibutton(app.Panel37, 'push');
            app.Button18_3.Icon = 'images6.jpeg';
            app.Button18_3.Text = '';
            app.Button18 = uibutton(app.Panel37, 'push');
            app.Button18.Icon = 'images9.jpg';
            app.Button18.Text = '';
            app.TextArea3_5 = uitextarea(app.Panel37);
            app.TextArea3_5.Editable = 'off';
            app.TextArea3_5.Value = {'Copyright © 2017 Digital Communication System Design. All Rights Reserved.'};
            app.Today_Date_2 = uitextarea(app.Panel37);
            app.DesignandTransmissionButton = uibutton(app.Panel37, 'push');
            app.DesignandTransmissionButton.Text = 'Design and Transmission';
            app.PerformanceAnalysisButton = uibutton(app.Panel37, 'push');
            app.PerformanceAnalysisButton.Text = 'Performance Analysis';
            app.AboutButton = uibutton(app.Panel37, 'push');
            app.AboutButton.Text = 'About';
            app.Tab5 = uitab(app.TabGroup);
            app.Tab5.Title = 'Design and Transmission';
            app.Panel23 = uipanel(app.Tab5);
            app.Panel23.Title = 'Transmitter';
            app.Label83 = uilabel(app.Panel23);
            app.Label83.Text = 'Quantizer';
            app.Quantizer_level = uidropdown(app.Panel23);
            app.Quantizer_level.Items = {'8', '4', '16', '32'};
            app.Quantizer_level.Value = '8';
            app.Label86 = uilabel(app.Panel23);
            app.Label86.Text = 'Modulator';
            app.Modulation_type = uidropdown(app.Panel23);
            app.Modulation_type.Items = {'PAM', 'PSK', 'QAM'};
            app.Modulation_type.Value = 'QAM';
            app.Label87 = uilabel(app.Panel23);
            app.Label87.Text = 'Modulation Order';
            app.Modulation_order = uidropdown(app.Panel23);
            app.Modulation_order.Items = {'2', '4', '16'};
            app.Modulation_order.Value = '16';
            app.Label89 = uilabel(app.Panel23);
            app.Label89.Text = 'SNR (dB)';
            app.SNR = uieditfield(app.Panel23, 'numeric');
            app.SNR.Value = 10;
            app.Panel24 = uipanel(app.Tab5);
            app.Panel24.Title = 'Channel';
            app.Label90 = uilabel(app.Panel24);
            app.Label90.Text = 'Fading Channel Type';
            app.Fading_Channel = uidropdown(app.Panel24);
            app.Fading_Channel.Items = {'ISI Channel(Special)', 'Measured Channel', 'Exponential Channel', 'JTC Channel', 'No Fading'};
            app.Fading_Channel.Enable = 'off';
            app.Fading_Channel.Value = 'No Fading';
            app.Label91 = uilabel(app.Panel24);
            app.Label91.Text = 'Channel';
            app.Channel = uidropdown(app.Panel24);
            app.Channel.Items = {'AWGN', 'AWGN + Fading'};
            app.Channel.ValueChangedFcn = createCallbackFcn(app, @ChannelValueChanged, true);
            app.Channel.Value = 'AWGN';
            app.PhaseShiftDegreeLabel = uilabel(app.Panel24);
            app.PhaseShiftDegreeLabel.Text = 'Phase Shift (Degree)';
            app.Phase_Shift = uieditfield(app.Panel24, 'numeric');
            app.Phase_Shift.Limits = [0 45];
            app.Phase_Shift.Value = 35;
            app.Panel25 = uipanel(app.Tab5);
            app.Panel25.Title = 'Receiver';
            app.Label92 = uilabel(app.Panel25);
            app.Label92.Text = 'Symbol Timing Recovery';
            app.Symbol_Timing = uidropdown(app.Panel25);
            app.Symbol_Timing.Items = {'Early Late Gate', 'Gradient Based', 'Mueller and Muller'};
            app.Symbol_Timing.Value = 'Early Late Gate';
            app.Label93 = uilabel(app.Panel25);
            app.Label93.Text = 'Blind Equalizer';
            app.Equalizer = uidropdown(app.Panel25);
            app.Equalizer.Items = {'VSCMA_equalizer', 'VSCMADDLMS_equalizer', 'DFECMA_equalizer', 'Constant Modulus Algorithm', 'Multiple Modulus Algorithm', 'Without Equalizer'};
            app.Equalizer.Enable = 'off';
            app.Equalizer.Value = 'Without Equalizer';
            app.Label94 = uilabel(app.Panel25);
            app.Label94.Text = 'Carrier Synchronizer';
            app.Carrier_Recovery = uidropdown(app.Panel25);
            app.Carrier_Recovery.Items = {'Costas Loop', 'Without Recovery'};
            app.Carrier_Recovery.Value = 'Costas Loop';
            app.File_Path = uieditfield(app.Tab5, 'text');
            app.Browser = uibutton(app.Tab5, 'push');
            app.Browser.ButtonPushedFcn = createCallbackFcn(app, @BrowserButtonPushed, true);
            app.Browser.Text = 'Browser';
            app.Run = uibutton(app.Tab5, 'state');
            app.Run.ValueChangedFcn = createCallbackFcn(app, @RunValueChanged, true);
            app.Run.Text = 'Run';
            app.Panel31 = uipanel(app.Tab5);
            app.Panel31.Title = 'Results Plot';
            app.Figure_1 = uiaxes(app.Panel31);
            app.Figure_1_Plots = uibuttongroup(app.Panel31);
            app.Figure_1_Plots.SelectionChangedFcn = createCallbackFcn(app, @Figure_1_PlotsSelectionChanged, true);
            app.Audio_Plot_Rx = uitogglebutton(app.Figure_1_Plots);
            app.Audio_Plot_Rx.Text = 'Audio Signal (Rx)';
            app.Image_Plot_Rx = uitogglebutton(app.Figure_1_Plots);
            app.Image_Plot_Rx.Text = 'Received Image';
            app.Image_Plot_Rx.Value = true;
            app.Image_Plot_Tx = uitogglebutton(app.Figure_1_Plots);
            app.Image_Plot_Tx.Text = 'Input Image';
            app.Audio_Plot_Tx = uitogglebutton(app.Figure_1_Plots);
            app.Audio_Plot_Tx.Text = 'Audio Signal (Tx)';
            app.Button19 = uibutton(app.Panel31, 'push');
            app.Button19.Icon = 'prev.png';
            app.Button19.Text = '';
            app.Button20 = uibutton(app.Panel31, 'push');
            app.Button20.Icon = 'next.png';
            app.Button20.Text = '';
            app.Button21 = uibutton(app.Panel31, 'push');
            app.Button21.ButtonPushedFcn = createCallbackFcn(app, @Button21Pushed, true);
            app.Button21.Icon = 'next.png';
            app.Button21.Text = '';
            app.Button22 = uibutton(app.Panel31, 'push');
            app.Button22.ButtonPushedFcn = createCallbackFcn(app, @Button22Pushed, true);
            app.Button22.Icon = 'prev.png';
            app.Button22.Text = '';
            app.Figure_2 = uiaxes(app.Panel31);
            app.Figure_2_Plots = uibuttongroup(app.Panel31);
            app.Figure_2_Plots.SelectionChangedFcn = createCallbackFcn(app, @Figure_2_PlotsSelectionChanged, true);
            app.Carrier_Estimator_Plot = uitogglebutton(app.Figure_2_Plots);
            app.Carrier_Estimator_Plot.Text = 'Carrier Estimation';
            app.Symbol_Timing_Plot = uitogglebutton(app.Figure_2_Plots);
            app.Symbol_Timing_Plot.Text = 'Symbol Timing';
            app.Equalization_Plot = uitogglebutton(app.Figure_2_Plots);
            app.Equalization_Plot.Text = 'Equalizer';
            app.Equalization_Plot.Value = true;
            app.Constellation_Tx_Plot = uitogglebutton(app.Figure_2_Plots);
            app.Constellation_Tx_Plot.Text = 'Constellation(Tx)';
            app.Constellation_Rx_Plot = uitogglebutton(app.Figure_2_Plots);
            app.Constellation_Rx_Plot.Text = 'Constellation(Rx)';
            app.ButtonGroup11 = uibuttongroup(app.Panel31);
            app.Label107 = uilabel(app.ButtonGroup11);
            app.Label107.Text = 'BER- Performance';
            app.BER = uieditfield(app.ButtonGroup11, 'numeric');
            app.Panel26 = uipanel(app.Tab5);
            app.Panel26.Title = 'Description';
            app.Description_3 = uitextarea(app.Panel26);
            app.Description_3.Editable = 'off';
            app.Description_3.Value = {'Costas Loop is used for carrier recovery in the receiver. It is designed to trace the incoming phase of the signal  for PAM, and 4-QAM modulation schemes.'; 'Best result of Costas loop is present when used in non-fading (wireline) channel. '; ''; 'The multi module algorithm (MMA) used for equalization plays a major role in carrier recovery as well. And proves useful in the presence of fading channel and phase shfit recovery.'; ''; 'The early-late gate is robust in the presence of noise and fades than the rest. Hence employed for timing recovery in this system.'};
            app.Selection = uidropdown(app.Tab5);
            app.Selection.Items = {'Random Data', 'Image', 'Audio'};
            app.Selection.Value = 'Random Data';
            app.TextArea3_6 = uitextarea(app.Tab5);
            app.TextArea3_6.Editable = 'off';
            app.TextArea3_6.Value = {'Copyright © 2017 Digital Communication System Design. All Rights Reserved.                                                                                                     '};
            app.Today_Date = uitextarea(app.Tab5);
            app.Figure_3 = uipanel(app.Tab5);
            app.Image_Figure = uiaxes(app.Figure_3);
            app.Tab5_2 = uitab(app.TabGroup);
            app.Tab5_2.Title = 'Performance Analysis';
            app.Panel23_2 = uipanel(app.Tab5_2);
            app.Panel23_2.Title = 'Performance Analysis';
            app.BlockSelectionLabel = uilabel(app.Panel23_2);
            app.BlockSelectionLabel.Text = 'Block Selection';
            app.Selected_Block = uidropdown(app.Panel23_2);
            app.Selected_Block.Items = {'Carrier Recovery', 'Symbol Timing Recovery', 'Early-Late Gate', 'Gradient Based', 'Mueller and Muller', 'Equalizer'};
            app.Selected_Block.ValueChangedFcn = createCallbackFcn(app, @DescriptionValueChanged, true);
            app.Selected_Block.Value = 'Carrier Recovery';
            app.GuidingEquationsandVariablesPanel = uipanel(app.Panel23_2);
            app.GuidingEquationsandVariablesPanel.Title = 'Guiding Equations and Variables';
            app.Description = uitextarea(app.GuidingEquationsandVariablesPanel);
            app.Description.ValueChangedFcn = createCallbackFcn(app, @DescriptionValueChanged, true);
            app.Description.Editable = 'off';
            app.Description.Value = {'K = Loop filter constant and fc = Loop filter cutoff frequency (as a fraction of maximum frequency allowable).'};
            app.Run_2 = uibutton(app.Panel23_2, 'state');
            app.Run_2.ValueChangedFcn = createCallbackFcn(app, @Run_2ValueChanged, true);
            app.Run_2.Text = 'Run';
            app.Run_2.Value = true;
            app.Panel = uipanel(app.Panel23_2);
            app.Previous = uibutton(app.Panel, 'push');
            app.Previous.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.Previous.Icon = 'prev.jpg';
            app.Previous.Text = '';
            app.Next = uibutton(app.Panel, 'push');
            app.Next.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.Next.Icon = 'btr_5.jpeg';
            app.Next.Text = '';
            app.Noisy_Channel = uicheckbox(app.Panel23_2);
            app.Noisy_Channel.Text = '';
            app.NoisyChannelLabel = uilabel(app.Panel23_2);
            app.NoisyChannelLabel.Text = 'Noisy Channel';
            app.SymbolTimingDesignLabel = uilabel(app.Panel23_2);
            app.SymbolTimingDesignLabel.Text = 'Symbol Timing Design';
            app.SNR_Value = uieditfield(app.Panel23_2, 'numeric');
            app.SNR_Value.Limits = [1 Inf];
            app.SNR_Value.Value = 10;
            app.SNREditFieldLabel = uilabel(app.Panel23_2);
            app.SNREditFieldLabel.Text = 'SNR';
            app.StepSizeKLabel = uilabel(app.Panel23_2);
            app.StepSizeKLabel.Text = 'Step Size (K)';
            app.K_constant = uieditfield(app.Panel23_2, 'numeric');
            app.K_constant.Value = 10;
            app.TimingPhaseDeviationLabel = uilabel(app.Panel23_2);
            app.TimingPhaseDeviationLabel.Text = 'Timing Phase Deviation (δτ)';
            app.SampleSpacing_delta = uieditfield(app.Panel23_2, 'numeric');
            app.SampleSpacing_delta.Value = 0.25;
            app.Panel31_2 = uipanel(app.Tab5_2);
            app.Panel31_2.Title = 'Results Plot';
            app.UIAxes_2 = uiaxes(app.Panel31_2);
            app.UIAxes_1 = uiaxes(app.Panel31_2);
            app.Title_Bar_1 = uilabel(app.Panel31_2);
            app.Title_Bar_1.Text = '';
            app.Title_Bar_2 = uilabel(app.Panel31_2);
            app.Title_Bar_2.Text = '';
            app.TextArea3_7 = uitextarea(app.Tab5_2);
            app.TextArea3_7.Editable = 'off';
            app.TextArea3_7.Value = {'Copyright © 2017 Digital Communication System Design. All Rights Reserved.                                                                                                     '};
            app.Today_Date_3 = uitextarea(app.Tab5_2);
            app.Panel31_3 = uipanel(app.Tab5_2);
            app.UIAxes_3 = uiaxes(app.Panel31_3);
            app.UIAxes_4 = uiaxes(app.Panel31_3);
            app.Title_Bar_3 = uilabel(app.Panel31_3);
            app.Title_Bar_3.Text = '';
            app.Title_Bar_4 = uilabel(app.Panel31_3);
            app.Title_Bar_4.Text = '';
            app.Panel23_3 = uipanel(app.Tab5_2);
            app.Panel23_3.Title = 'Block Diagram and Algorithms';
            app.Blocks = uibutton(app.Panel23_3, 'push');
            app.Blocks.Icon = 'Algorithm_Best.png';
            app.Blocks.Text = '';
            app.Panel_2 = uipanel(app.Panel23_3);
            app.Prev_Image = uibutton(app.Panel_2, 'push');
            app.Prev_Image.ButtonPushedFcn = createCallbackFcn(app, @Prev_ImageButtonPushed, true);
            app.Prev_Image.Icon = 'icon.jpeg';
            app.Prev_Image.Text = '';
            app.Next_Image = uibutton(app.Panel_2, 'push');
            app.Next_Image.ButtonPushedFcn = createCallbackFcn(app, @Next_ImageButtonPushed, true);
            app.Next_Image.Icon = 'Icon_2.jpeg';
            app.Next_Image.Text = '';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.Panel26_2 = uipanel(app.AboutTab);
            app.Panel26_2.Title = 'About';
            app.Description_5 = uitextarea(app.Panel26_2);
            app.Description_5.Editable = 'off';
            app.Description_5.Value = {'This is a MATLAB based GUI application designed and developed to simulate and test the performance of digital communication system blocks and algorithms residing within each block.'; ''; 'Application Designed by: Leul Wuletaw   Supervised by: Dr. Ing. Dereje Hailemariam'; 'Assistance on app designing and equalization from: Tewodros Amsalu (Msc in Communication Engineering)'; ''};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Digital_Communication_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end